classdef RAS_START < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RASUIFigure             matlab.ui.Figure
        Image9                  matlab.ui.control.Image
        Image8                  matlab.ui.control.Image
        Image7                  matlab.ui.control.Image
        Image6                  matlab.ui.control.Image
        JerryTechnologyLabel    matlab.ui.control.Label
        Image5                  matlab.ui.control.Image
        Switch                  matlab.ui.control.Switch
        Label_5                 matlab.ui.control.Label
        statusEditField         matlab.ui.control.EditField
        Label_4                 matlab.ui.control.Label
        STARTButton             matlab.ui.control.Button
        DropDown_3              matlab.ui.control.DropDown
        Label_3                 matlab.ui.control.Label
        RobotAdjustSystemLabel  matlab.ui.control.Label
        Image4                  matlab.ui.control.Image
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        COMDropDown             matlab.ui.control.DropDown
        Label                   matlab.ui.control.Label
        Button                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        availablePorts_info
        availablecom
        serial_obj
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
           try
                app.availablePorts_info=get_port();
                 app.COMDropDown.Items= app.availablePorts_info;
           catch
                errordlg('未找到任何可用串口!', '连接错误');


           end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
           

            if  app.serial_obj.get_hearstatus()==1

            else
                  errordlg('未从该串口收到有效信息!', '连接错误');
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
             if strcmp(value, 'on')
                app.availablecom=extractBetween(app.COMDropDown.Value, "(", ")");
                   try
                       app.serial_obj= rc9_serial;
                       app.serial_obj.connect(char(app.availablecom));
                       
                       app.statusEditField.Value="连接到"+string(app.availablecom);
                                          
                   catch
                         errordlg('无法连接到该串口!', '连接错误');
        
                   end
               
            else
               %disp('off');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            errordlg('敬请期待!', '功能选择错误');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
              errordlg('敬请期待!', '功能选择错误');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RASUIFigure = uifigure('Visible', 'off');
            app.RASUIFigure.Name = 'RAS 起始界面';
            app.RASUIFigure.Icon = fullfile(pathToMLAPP, '好图', '微信图片_20241103115504.jpg');
            app.Button = uibutton(app.RASUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '搜索';
            app.Label = uilabel(app.RASUIFigure);
            app.Label.Text = 'COM';
            app.COMDropDown = uidropdown(app.RASUIFigure);
            app.COMDropDown.Items = {};
            app.COMDropDown.Value = {};
            app.Image = uiimage(app.RASUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '好图', '微信图片_20241103111715.png');
            app.Image2 = uiimage(app.RASUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '好图', '472309f790529822720ef494fa9c6ccb0a46f21f7e17.jpg');
            app.Image3 = uiimage(app.RASUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '好图', 'QQ截图20241103115038.jpg');
            app.Button_2 = uibutton(app.RASUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '投喂';
            app.Button_3 = uibutton(app.RASUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '使用手册';
            app.Image4 = uiimage(app.RASUIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '好图', '微信图片_20241103115504.jpg');
            app.RobotAdjustSystemLabel = uilabel(app.RASUIFigure);
            app.RobotAdjustSystemLabel.Text = 'Robot Adjust System';
            app.Label_3 = uilabel(app.RASUIFigure);
            app.Label_3.Text = '模式选择';
            app.DropDown_3 = uidropdown(app.RASUIFigure);
            app.DropDown_3.Items = {'示波器', '底盘', '电机', '决策', '定位', '滤波', '驾驶员'};
            app.DropDown_3.Value = '示波器';
            app.STARTButton = uibutton(app.RASUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Icon = fullfile(pathToMLAPP, '好图', '开始巡检.png');
            app.STARTButton.Text = 'START';
            app.Label_4 = uilabel(app.RASUIFigure);
            app.Label_4.Text = 'status';
            app.statusEditField = uieditfield(app.RASUIFigure, 'text');
            app.statusEditField.Value = '未连接';
            app.Label_5 = uilabel(app.RASUIFigure);
            app.Label_5.Text = '';
            app.Switch = uiswitch(app.RASUIFigure, 'slider');
            app.Switch.Items = {'off', 'on'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'off';
            app.Image5 = uiimage(app.RASUIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '好图', '微信图片_20241103171503.png');
            app.JerryTechnologyLabel = uilabel(app.RASUIFigure);
            app.JerryTechnologyLabel.Text = 'Jerry Technology';
            app.Image6 = uiimage(app.RASUIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, '好图', '机器人-copy.png');
            app.Image7 = uiimage(app.RASUIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, '好图', '齿轮.png');
            app.Image8 = uiimage(app.RASUIFigure);
            app.Image8.ImageSource = fullfile(pathToMLAPP, '好图', '底盘分厂.png');
            app.Image9 = uiimage(app.RASUIFigure);
            app.Image9.ImageSource = fullfile(pathToMLAPP, '好图', 'robot-2-line.png');
            % Show the figure after all components are created
            app.RASUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RAS_START

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RASUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RASUIFigure)
        end
    end
end